classdef aljazDRAWapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        PlotListBoxLabel     matlab.ui.control.Label
        PlotListBox          matlab.ui.control.ListBox
        SubjectSpinnerLabel  matlab.ui.control.Label
        SubjectSpinner       matlab.ui.control.Spinner
        TargetSpinnerLabel   matlab.ui.control.Label
        TargetSpinner        matlab.ui.control.Spinner
        UIAxes2              matlab.ui.control.UIAxes
        UIAxesQuiver         matlab.ui.control.UIAxes
        HealthyLampLabel     matlab.ui.control.Label
        HealthyLamp          matlab.ui.control.Lamp
        LLampLabel           matlab.ui.control.Label
        LLamp                matlab.ui.control.Lamp
        RLampLabel           matlab.ui.control.Label
        RLamp                matlab.ui.control.Lamp
        HandLabel            matlab.ui.control.Label
        drawstdCheckBox      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        hap % Description
        meanAccArray
        calculated
        stdAccArray % Description
    end
    
    methods (Access = private)
        
        function plotStuff(app)
            %load('haptic_data.mat');
            %haptic_data=app.hap;
            damping_no=3;
            value = app.PlotListBox.Value;
            Wn = 20/100;                   % Normalized cutoff frequency
            [Fb,Fa]=butter(4,Wn);
            subject_no=app.SubjectSpinner.Value;
            target_no=app.TargetSpinner.Value;
            if value=="Acc Porazdelitev"
                
                
                idxStart=1+(target_no-1)*200;
                idxEnd=idxStart+199;
                
                trajY1=app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(1).U(idxStart:idxEnd);
                trajZ1=app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(1).V(idxStart:idxEnd);
                velY1=diff(trajY1);
                velZ1=diff(trajZ1);
                accY1=diff(velY1);
                accZ1=diff(velZ1);
                
                trajY2=app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(2).U(idxStart:idxEnd);
                trajZ2=app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(2).V(idxStart:idxEnd);
                velY2=diff(trajY2);
                velZ2=diff(trajZ2);
                accY2=diff(velY2);
                accZ2=diff(velZ2);
                
                accY1f=filter(Fb,Fa,accY1);
                accZ1f=filter(Fb,Fa,accZ1);
                accY2f=filter(Fb,Fa,accY2);
                accZ2f=filter(Fb,Fa,accZ2);
                
                %figure()
                %hold on
                plot(app.UIAxes,[1:length(accY1f)],accY1f,[1:length(accY2f)],accY2f);
                title(app.UIAxes,"acc Y");
                legend(app.UIAxes,'repetition 1','repetition 2')
                plot(app.UIAxes2,[1:length(accZ1f)],accZ1f,[1:length(accZ2f)],accZ2f);
                title(app.UIAxes2,"acc Z");
                legend(app.UIAxes2,'repetition 1','repetition 2')
                %hold off
                
            end
            if value=="Acc Porazdelitev Mean"
                app.drawstdCheckBox.Visible=true;
                if app.calculated(2)==0
                    accArrayF=zeros(29,198,32,2); %dimenzije: (subject_no, normalized_time, target_no+16*(repetition-1), 1=y 2=z)
                    %calculate stuff
                    for target_no=1:16
                        for subject_no=1:29
                            idxStart=1+(target_no-1)*200;
                            idxEnd=idxStart+199;
                            
                            trajY1=app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(1).U(idxStart:idxEnd);
                            trajZ1=app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(1).V(idxStart:idxEnd);
                            velY1=diff(trajY1);
                            velZ1=diff(trajZ1);
                            accY1=diff(velY1);
                            accZ1=diff(velZ1);
                            
                            trajY2=app.hap.haptic_data(subject_no).damping(damping_no).position_y_path(2).U(idxStart:idxEnd);
                            trajZ2=app.hap.haptic_data(subject_no).damping(damping_no).position_z_path(2).V(idxStart:idxEnd);
                            velY2=diff(trajY2);
                            velZ2=diff(trajZ2);
                            accY2=diff(velY2);
                            accZ2=diff(velZ2);
                            
                            accArrayF(subject_no,:,target_no,1)=filter(Fb,Fa,accY1);
                            accArrayF(subject_no,:,target_no,2)=filter(Fb,Fa,accZ1);
                            accArrayF(subject_no,:,target_no+16,1)=filter(Fb,Fa,accY2);
                            accArrayF(subject_no,:,target_no+16,2)=filter(Fb,Fa,accZ2);
                        end
                    end
                    
                    app.meanAccArray=mean(accArrayF);
                    app.meanAccArray=reshape(app.meanAccArray,[198,32,2]);
                    app.stdAccArray=std(accArrayF);
                    app.stdAccArray=reshape(app.stdAccArray,[198,32,2]);
                    app.calculated(2)=1;
                end
                if app.calculated(2)==1
                    if app.drawstdCheckBox.Value==true
                        plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1),[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1),[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1)+app.stdAccArray(:,target_no,1),'b',[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1)+app.stdAccArray(:,target_no+16,1),'r',[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1)-app.stdAccArray(:,target_no,1),'b',[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1)-app.stdAccArray(:,target_no+16,1),'r');
                        title(app.UIAxes,"mean+std acc Y");
                        legend(app.UIAxes,'repetition 1','repetition 2')
                        plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2),[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2),[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2)+app.stdAccArray(:,target_no,2),'b',[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2)+app.stdAccArray(:,target_no+16,2),'r',[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2)-app.stdAccArray(:,target_no,2),'b',[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2)-app.stdAccArray(:,target_no+16,2),'r');
                        title(app.UIAxes2,"mean+std acc Z");
                        legend(app.UIAxes2,'repetition 1','repetition 2')
                        
                    else
                        plot(app.UIAxes,[1:length(app.meanAccArray(:,target_no,1))],app.meanAccArray(:,target_no,1),[1:length(app.meanAccArray(:,target_no+16,1))],app.meanAccArray(:,target_no+16,1));
                        title(app.UIAxes,"mean acc Y");
                        legend(app.UIAxes,'repetition 1','repetition 2')
                        plot(app.UIAxes2,[1:length(app.meanAccArray(:,target_no,2))],app.meanAccArray(:,target_no,2),[1:length(app.meanAccArray(:,target_no+16,2))],app.meanAccArray(:,target_no+16,2));
                        title(app.UIAxes2,"mean acc Z");
                        legend(app.UIAxes2,'repetition 1','repetition 2')
                    end
                end
            else
                app.drawstdCheckBox.Visible=false;
            end
        end
        
        function drawQuiver(app,target)
            if target==1
                quiver(app.UIAxesQuiver,0.7,0.1,0.6,0);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==2
                quiver(app.UIAxesQuiver,1.3,0.1,0,0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==3
                quiver(app.UIAxesQuiver,1.3,0.9,-0.6,-0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==4
                quiver(app.UIAxesQuiver,0.7,0.1,-0.6,0);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==5
                quiver(app.UIAxesQuiver,0.1,0.1,1.2,0);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==6
                quiver(app.UIAxesQuiver,1.3,0.1,-0.6,0);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==7
                quiver(app.UIAxesQuiver,0.7,0.1,-0.6,0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==8
                quiver(app.UIAxesQuiver,0.1,0.9,0,-0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==9
                quiver(app.UIAxesQuiver,0.1,0.1,0.6,0);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==10
                quiver(app.UIAxesQuiver,0.7,0.1,0,0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==11
                quiver(app.UIAxesQuiver,0.7,0.9,0,-0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==12
                quiver(app.UIAxesQuiver,0.7,0.1,0.6,0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==13
                quiver(app.UIAxesQuiver,1.3,0.9,0,-0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==14
                quiver(app.UIAxesQuiver,1.3,0.1,-1.2,0);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==15
                quiver(app.UIAxesQuiver,0.1,0.1,0,0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            if target==16
                quiver(app.UIAxesQuiver,0.1,0.9,0.6,-0.8);
                axis(app.UIAxesQuiver,[0,1.4,0,1]);
            end
            
        end
        
        function updateSubjectinfo(app,subj)
            if subj<20
                app.HealthyLamp.Color=[0,1,0];
                app.HandLabel.Text="Dominant Hand";
            else
                app.HealthyLamp.Color=[0,0.1,0];
                app.HandLabel.Text="Healthy Hand";
            end
            left=[0,0,0,0,0,0,0,0,1,0,0,0,0,0,0,0,0,0,0,1,1,1,1,1,1,1,0,0,0];
            if left(subj)==0
                app.RLamp.Color=[0,1,0];
                app.LLamp.Color=[0,0.1,0];
                
            else
                app.RLamp.Color=[0,0.1,0];
                app.LLamp.Color=[0,1,0];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hap=load("haptic_data.mat");
            app.calculated=zeros(size(app.PlotListBox.Items));
            plotStuff(app);
            drawQuiver(app,app.TargetSpinner.Value);
            updateSubjectinfo(app,app.SubjectSpinner.Value);
        end

        % Value changed function: PlotListBox
        function PlotListBoxValueChanged(app, event)
            plotStuff(app);
        end

        % Value changed function: SubjectSpinner
        function SubjectSpinnerValueChanged(app, event)
            plotStuff(app);
            updateSubjectinfo(app,app.SubjectSpinner.Value);
        end

        % Value changed function: TargetSpinner
        function TargetSpinnerValueChanged(app, event)
            plotStuff(app);
            drawQuiver(app,app.TargetSpinner.Value);
        end

        % Value changed function: drawstdCheckBox
        function drawstdCheckBoxValueChanged(app, event)
            value = app.drawstdCheckBox.Value;
            plotStuff(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotListBoxLabel = uilabel(app.UIFigure);
            app.PlotListBoxLabel.Text = 'Plot';
            app.PlotListBox = uilistbox(app.UIFigure);
            app.PlotListBox.Items = {'Acc Porazdelitev', 'Acc Porazdelitev Mean', 'Item 3', 'Item 4', 'Item 5'};
            app.PlotListBox.ValueChangedFcn = createCallbackFcn(app, @PlotListBoxValueChanged, true);
            app.PlotListBox.Value = 'Acc Porazdelitev';
            app.SubjectSpinnerLabel = uilabel(app.UIFigure);
            app.SubjectSpinnerLabel.Text = 'Subject';
            app.SubjectSpinner = uispinner(app.UIFigure);
            app.SubjectSpinner.Limits = [1 29];
            app.SubjectSpinner.ValueChangedFcn = createCallbackFcn(app, @SubjectSpinnerValueChanged, true);
            app.SubjectSpinner.Value = 1;
            app.TargetSpinnerLabel = uilabel(app.UIFigure);
            app.TargetSpinnerLabel.Text = 'Target';
            app.TargetSpinner = uispinner(app.UIFigure);
            app.TargetSpinner.Limits = [1 16];
            app.TargetSpinner.ValueChangedFcn = createCallbackFcn(app, @TargetSpinnerValueChanged, true);
            app.TargetSpinner.Value = 1;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxesQuiver = uiaxes(app.UIFigure);
            title(app.UIAxesQuiver, 'Move Direction')
            xlabel(app.UIAxesQuiver, 'Y')
            ylabel(app.UIAxesQuiver, 'Z')
            app.HealthyLampLabel = uilabel(app.UIFigure);
            app.HealthyLampLabel.Text = 'Healthy';
            app.HealthyLamp = uilamp(app.UIFigure);
            app.LLampLabel = uilabel(app.UIFigure);
            app.LLampLabel.Text = 'L';
            app.LLamp = uilamp(app.UIFigure);
            app.RLampLabel = uilabel(app.UIFigure);
            app.RLampLabel.Text = 'R';
            app.RLamp = uilamp(app.UIFigure);
            app.HandLabel = uilabel(app.UIFigure);
            app.HandLabel.Text = 'Hand';
            app.drawstdCheckBox = uicheckbox(app.UIFigure);
            app.drawstdCheckBox.ValueChangedFcn = createCallbackFcn(app, @drawstdCheckBoxValueChanged, true);
            app.drawstdCheckBox.Text = 'draw std';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aljazDRAWapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end